classdef DropTowerFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        AceleracinEditField_3Label     matlab.ui.control.Label
        AceleracionEditField           matlab.ui.control.NumericEditField
        AlturaTorreLabel               matlab.ui.control.Label
        AlturaTorreEditField           matlab.ui.control.NumericEditField
        GRAFICARButton                 matlab.ui.control.Button
        VelocidadEditField_3Label      matlab.ui.control.Label
        VelocidadEditField             matlab.ui.control.NumericEditField
        CONVERTIRButton                matlab.ui.control.Button
        JerkEditFieldLabel             matlab.ui.control.Label
        JerkEditField                  matlab.ui.control.NumericEditField
        AlturadeFrenadoEditFieldLabel  matlab.ui.control.Label
        AlturadeFrenadoEditField       matlab.ui.control.NumericEditField
        msLabel                        matlab.ui.control.Label
        ms2Label                       matlab.ui.control.Label
        ms3Label                       matlab.ui.control.Label
        VelocidadEditField_2           matlab.ui.control.NumericEditField
        kmhrLabel                      matlab.ui.control.Label
        gLabel                         matlab.ui.control.Label
        gsLabel                        matlab.ui.control.Label
        AceleracionEditField_2         matlab.ui.control.NumericEditField
        JerkEditField_2                matlab.ui.control.NumericEditField
        ValoresMximosLabel             matlab.ui.control.Label
        ValoresdealturaLabel           matlab.ui.control.Label
        VelocidadFinalLabel            matlab.ui.control.Label
        VelocidadFinalEditField        matlab.ui.control.NumericEditField
        msLabel_2                      matlab.ui.control.Label
        VelocidadFinalEditField_2      matlab.ui.control.NumericEditField
        kmhrLabel_2                    matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GRAFICARButton
        function GRAFICARButtonPushed(app, event)
            %{
            Reto Mod. Sistemas Electromagnéticos 'Drop Tower'
            Erick Alberto Bustos Cruz - A01378966
            Diana Cañibe Valle - A01749422
            Itzel Aurora Carbajal Martínez - A1750165
            Jared Abraham Flores Guarneros - A01379868
            %}
            
            ht = app.AlturaTorreEditField.Value;
            hf = app.AlturadeFrenadoEditField.Value;
            
            app.UIAxes.cla;
            app.UIAxes2.cla;

            m= 4000;       %masa
            g = 9.81;      %gravedad
            r = 3.9;       %radio
            B = 0.00025;   %campo magnético
            ro = 1.71e-08; %resistividad del cobre
            t_end = 100;    %Duración total de la simulación
            h=  0.01;

            alfa = ((8*(r^2)*hf*(B^2))/(ro*pi)); % Constante las corrientes de Eddy

            % Caida Libre
            vi = @(t) g * t;
            tf = ((2 * g * (ht-hf)) ^(1/2))/g;
            t1 = 0:h:tf;
            V0 = vi(t1); % Velocidad durante la caída libre
            t2 = tf:h:t_end;

            % Frenado Magnético
            v2 = @(t) (V0(end) - ((m*g)/alfa))*(exp((-alfa/m)*(t - tf))) + (m*g)/alfa;
            V=v2(t2); % Velocidad durante el frenado
            a= @(t) (1/m) * exp(alfa * (-t + tf)/m) *(g*m - alfa * V0(end)) ;
            A=a(t2); % Aceleración durante el frenado
            j= @(t) (1/(m^2)) * alfa * exp(alfa * (-t +tf) / m) * (-g * m + alfa * V0(end));
            J=j(t2); % Jerk durante el frenado

            % Altura vs Tiempo
            hi=@(t) ht- (1/2)* g .* t.^2 ;
            h_caida=hi(t1);

            h2 = @(t) hf - (1/(alfa^2)) * (alfa*m*-g*tf + m *(-g*m - alfa*-V0(end))) +(1/(alfa^2)) * (alfa * m * -g .* t + exp(alfa .* (-t +tf)./m) .* m *( -g* m - alfa * -V0(end)));
            h_freno= h2(t2);

            plot(app.UIAxes,[V0 V],[h_caida h_freno],'b')
            axis(app.UIAxes,[0 55 0 ht+5])
            hold (app.UIAxes, 'on')
            plot(app.UIAxes,abs(A),h_freno,'r')
            hold (app.UIAxes, 'on')
            plot(app.UIAxes,J,h_freno,'m')
            hold (app.UIAxes, 'on')
            legend(app.UIAxes,"Velocidad", "Aceleración","Jerk")
            
            app.VelocidadEditField.Value=V(1);
            app.VelocidadFinalEditField.Value=V(end);
            app.AceleracionEditField.Value=A(1);
            app.JerkEditField.Value=J(1);
            
            stop= [h_freno>0];
            y = [h_caida h_freno(stop)];
            x = repelem(2,length(y));
            
            x1=[2 2];
            y1=[0 ht];
            
            plot(app.UIAxes2,x1,y1,"LineWidth",2,"Color",'g')
           
          
            hold(app.UIAxes2,'on')
           
            axis(app.UIAxes2,[0 4 0 ht+5])
            for i = 1:5:length(x)
      
                app.UIAxes2.cla;
                plot(app.UIAxes2,x1,y1,"LineWidth",2,"Color",'g')
                plot(app.UIAxes2,x(i),y(i),"bo")
                pause(0.05)
                drawnow
            end
            
            
        end

        % Button pushed function: CONVERTIRButton
        function CONVERTIRButtonPushed(app, event)
            ht = app.AlturaTorreEditField.Value;
            hf = app.AlturadeFrenadoEditField.Value;

            m= 4000;       %masa
            g = 9.81;      %gravedad
            r = 3.9;       %radio
            B = 0.00025;   %campo magnético
            ro = 1.71e-08; %resistividad del cobre
            t_end = 15;   % Duración total de la simulación

            alfa = ((8*(r^2)*hf*(B^2))/(ro*pi)); % Constante las corrientes de Eddy

            % Caida Libre
            vi = @(t) g * t;
            tf = ((2 * g * (ht-hf)) ^(1/2))/g;
            t1 = linspace(0,tf,100);
            V0 = vi(t1); % Velocidad durante la caída libre
            t2= linspace(tf,t_end,100);

            % Frenado Magnético
            v2 = @(t) (V0(end) - ((m*g)/alfa))*(exp((-alfa/m)*(t - tf))) + (m*g)/alfa;
            V=v2(t2); % Velocidad durante el frenado
            a= @(t) (1/m) * exp(alfa * (-t + tf)/m) *(g*m - alfa * V0(end)) ;
            A=a(t2); % Aceleración durante el frenado
            j= @(t) (1/(m^2)) * alfa * exp(alfa * (-t +tf) / m) * (-g * m + alfa * V0(end));
            J=j(t2); % Jerk durante el frenado
            
            VCon=V*3.6;
            ACon=A./g;
            JCon=J/g;
            
            app.VelocidadEditField_2.Value=VCon(1);
            app.VelocidadFinalEditField_2.Value=VCon(end);
            app.AceleracionEditField_2.Value=ACon(1);
            app.JerkEditField_2.Value=JCon(1);
            
            if JCon(1)>5
                app.JerkEditField_2.BackgroundColor=[1,0,0];
            else
                app.JerkEditField_2.BackgroundColor=[0,1,0];
            end
            
            if -2 <= ACon(1) &&  ACon(1)<=5
                app.AceleracionEditField_2.BackgroundColor=[0,1,0];
            else
                app.AceleracionEditField_2.BackgroundColor=[1,0,0];
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {510, 510};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {338, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AceleracinEditField_3Label = uilabel(app.LeftPanel);
            app.AceleracinEditField_3Label.Text = 'Aceleración';
            app.AceleracionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AceleracionEditField.ValueDisplayFormat = '%.4f';
            app.AlturaTorreLabel = uilabel(app.LeftPanel);
            app.AlturaTorreLabel.Text = 'Altura Torre';
            app.AlturaTorreEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlturaTorreEditField.Value = 64;
            app.GRAFICARButton = uibutton(app.LeftPanel, 'push');
            app.GRAFICARButton.ButtonPushedFcn = createCallbackFcn(app, @GRAFICARButtonPushed, true);
            app.GRAFICARButton.Text = 'GRAFICAR';
            app.VelocidadEditField_3Label = uilabel(app.LeftPanel);
            app.VelocidadEditField_3Label.Text = 'Velocidad ';
            app.VelocidadEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VelocidadEditField.ValueDisplayFormat = '%.4f';
            app.CONVERTIRButton = uibutton(app.LeftPanel, 'push');
            app.CONVERTIRButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTIRButtonPushed, true);
            app.CONVERTIRButton.Text = 'CONVERTIR';
            app.JerkEditFieldLabel = uilabel(app.LeftPanel);
            app.JerkEditFieldLabel.Text = {'Jerk'; ''};
            app.JerkEditField = uieditfield(app.LeftPanel, 'numeric');
            app.JerkEditField.ValueDisplayFormat = '%.4f';
            app.AlturadeFrenadoEditFieldLabel = uilabel(app.LeftPanel);
            app.AlturadeFrenadoEditFieldLabel.Text = {'Altura de Frenado '; ''};
            app.AlturadeFrenadoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlturadeFrenadoEditField.Value = 32;
            app.msLabel = uilabel(app.LeftPanel);
            app.msLabel.Text = 'm/s';
            app.ms2Label = uilabel(app.LeftPanel);
            app.ms2Label.Text = {'m/s2'; ''; ''};
            app.ms3Label = uilabel(app.LeftPanel);
            app.ms3Label.Text = 'm/s3';
            app.VelocidadEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.VelocidadEditField_2.ValueDisplayFormat = '%.4f';
            app.kmhrLabel = uilabel(app.LeftPanel);
            app.kmhrLabel.Text = {'km/hr'; ''};
            app.gLabel = uilabel(app.LeftPanel);
            app.gLabel.Text = {'g'; ''};
            app.gsLabel = uilabel(app.LeftPanel);
            app.gsLabel.Text = {'g/s'; ''};
            app.AceleracionEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.AceleracionEditField_2.ValueDisplayFormat = '%.4f';
            app.JerkEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.JerkEditField_2.ValueDisplayFormat = '%.4f';
            app.ValoresMximosLabel = uilabel(app.LeftPanel);
            app.ValoresMximosLabel.Text = 'Valores Máximos';
            app.ValoresdealturaLabel = uilabel(app.LeftPanel);
            app.ValoresdealturaLabel.Text = {'Valores de altura'; ''};
            app.VelocidadFinalLabel = uilabel(app.LeftPanel);
            app.VelocidadFinalLabel.Text = {'Velocidad '; 'Final'};
            app.VelocidadFinalEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VelocidadFinalEditField.ValueDisplayFormat = '%.4f';
            app.msLabel_2 = uilabel(app.LeftPanel);
            app.msLabel_2.Text = 'm/s';
            app.VelocidadFinalEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.VelocidadFinalEditField_2.ValueDisplayFormat = '%.4f';
            app.kmhrLabel_2 = uilabel(app.LeftPanel);
            app.kmhrLabel_2.Text = {'km/hr'; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Free Fall Tower')
            xlabel(app.UIAxes, {'[m/s] [m/s^2] [m/s^3]'; ''})
            ylabel(app.UIAxes, 'Altura [m]')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Drop Shot')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, 'Altura')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DropTowerFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end